classdef DataInladen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SynchronizeerButton   matlab.ui.control.StateButton
        TextArea_2            matlab.ui.control.TextArea
        Tree                  matlab.ui.container.CheckBoxTree
        EDFNode               matlab.ui.container.TreeNode
        PCO2Node              matlab.ui.container.TreeNode
        PCO2_DCNode           matlab.ui.container.TreeNode
        SpO2Node              matlab.ui.container.TreeNode
        PRNode                matlab.ui.container.TreeNode
        GDTNode               matlab.ui.container.TreeNode
        LoadNode              matlab.ui.container.TreeNode
        HRNode_2              matlab.ui.container.TreeNode
        V_O2_kgNode           matlab.ui.container.TreeNode
        V_O2_kgNode_2         matlab.ui.container.TreeNode
        V_ENode               matlab.ui.container.TreeNode
        BFNode                matlab.ui.container.TreeNode
        EqO2Node              matlab.ui.container.TreeNode
        EqCO2Node             matlab.ui.container.TreeNode
        RERNode               matlab.ui.container.TreeNode
        O2pulseNode           matlab.ui.container.TreeNode
        PaCO2Node             matlab.ui.container.TreeNode
        DataGDTinladenButton  matlab.ui.control.Button
        TextArea              matlab.ui.control.TextArea
        StartMatlabEDFfileAnalyzerButton  matlab.ui.control.Button
        DataEDFinladenButton  matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dataEDFTimeTable % to hold EDF data
        infoedf % to hold EDF fileinfo
        fs % Sample Rate
        colortable %Specifeert welke kleur bij welke datasoort hoort
        currentlyDrawnNodes
        dataGDTTimeTable % to hold GDT data
        gdtSampleRate %sample rate of GDT file
    end
    
    methods (Access = private)
     
        
        % function drawedfgraphs(app) %
        %     hold(app.UIAxes, 'on')
        %     t=(0:app.infoedf.NumDataRecords-1)/app.fs;
        % 
        %     %draw all checked nodes on first run or when an undrawn node
        %     %gets checked
        %     if (size(app.currentlyDrawnNodes) <= size(app.Tree.CheckedNodes))
        %         cla(app.UIAxes) %clear all previous plots
        %         for i=1:size(app.Tree.CheckedNodes) %For all checkednodes
        %             currentNode=app.Tree.CheckedNodes(i);
        %             if(isempty(currentNode.Children)) %Only draw when at bottom of tree
        %                 y=app.dataEDFTimeTable.(currentNode.Text);
        %                 plot(app.UIAxes,t,y,'DisplayName',currentNode.Text);             
        %             end           
        %         end
        % 
        %     %removeunchecked nodes
        %     else 
        %         toRemove=setdiff(app.currentlyDrawnNodes,app.Tree.CheckedNodes); % verschil tussen checkednodes en drawnnodes = Wat teveel geplot is
        %         for i=1:size(toRemove) %For all checkednodes
        %             currentNode=toRemove(i);
        %             if(isempty(currentNode.Children)) %Only remove when at bottom of tree
        %                 delete(findobj(app.UIAxes,'DisplayName',currentNode.Text));        
        %             end           
        %         end
        %     end
        %     legend(app.UIAxes);
        % 
        %     app.currentlyDrawnNodes=app.Tree.CheckedNodes; %Update huidige checkednodes
        % end
        
        function drawgraphs(app)
            if(~isempty(app.dataEDFTimeTable) & ~isempty(app.dataGDTTimeTable)) %Only draw when both files have been imported
                hold(app.UIAxes, 'on')
                
                t=app.dataEDFTimeTable.('Record Time');
                %draw all checked nodes on first run or when an undrawn node
                %gets checked
                if (size(app.currentlyDrawnNodes) <= size(app.Tree.CheckedNodes) )
                    cla(app.UIAxes) %clear all previous plots
                    for i=1:size(app.Tree.CheckedNodes) %For all checkednodes
                        currentNode=app.Tree.CheckedNodes(i);
                        if(isempty(currentNode.Children)) %Only draw when at bottom of tree
                            if((currentNode.Parent.Text == 'EDF') & ~isempty(app.dataEDFTimeTable) ) %In EDF en er is edfddata
                               
                               y=app.dataEDFTimeTable.(currentNode.Text);
                               plot(app.UIAxes,t,y,'DisplayName',(append('EDF ',currentNode.Text)));
                            elseif((currentNode.Parent.Text == 'GDT') & ~isempty(app.dataGDTTimeTable)) %In GDT en er is GDTddata
                               t=app.dataGDTTimeTable.('Time');
                               y=app.dataGDTTimeTable.(currentNode.Text);
                               plot(app.UIAxes,t,y,'DisplayName',(append('GDT ',currentNode.Text)));
                            end
                        end           
                    end
    
                %removeunchecked nodes
                else 
                    toRemove=setdiff(app.currentlyDrawnNodes,app.Tree.CheckedNodes); % verschil tussen checkednodes en drawnnodes = Wat teveel geplot is
                    for i=1:size(toRemove) %For all checkednodes
                        currentNode=toRemove(i);
                        if(isempty(currentNode.Children)) %Only remove when at bottom of tree
                            if(currentNode.Parent.Text == 'EDF')
                                delete(findobj(app.UIAxes,'DisplayName',(append('EDF ',currentNode.Text))));
                            elseif(currentNode.Parent.Text == 'GDT')
                                delete(findobj(app.UIAxes,'DisplayName',(append('GDT ',currentNode.Text))));
                            end

                        end
                    end
                end
               
                
            legend(app.UIAxes);

            app.currentlyDrawnNodes=app.Tree.CheckedNodes; %Update huidige checkednodes

            end


        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            

        end

        % Callback function
        function DataEDFInladenButtonValueChanged(app, event)

        end

        % Button pushed function: DataEDFinladenButton
        function DataEDFinladenButtonPushed(app, event)
            
            cla(app.UIAxes) % clear previous plots
            delete(app.currentlyDrawnNodes) %
            
            uiwait(msgbox("Selecteer EDF bestand van Capnografie metingen","modal"));   % Dialog box for opening EDF file
            [fileName,path] = uigetfile('*.edf')
            app.dataEDFTimeTable = edfread(fileName)
            app.infoedf = edfinfo(fileName)

            %Calculate sample rate:
            numSamples = app.infoedf.NumDataRecords;
            testDuration = app.infoedf.NumDataRecords;
            app.fs=numSamples/testDuration;

            %Show edf parameters
            textforconsole = sprintf("Patient: " + app.infoedf.Patient + "\nDate: " + app.infoedf.StartDate + "\nTime: " + app.infoedf.StartTime + "\nNumDataRecords: " + app.infoedf.NumDataRecords + "\nSample Rate: " + app.fs + " Hz")
            app.TextArea.Value = textforconsole
            
            %Assign colors to Signallabels
            % app.colortable=table(app.infoedf.SignalLabels,color)
            % for i=1:size(app.infoedf.SignalLabels)
            % 
            % end


            drawgraphs(app) %draw graphs function

        end

        % Callback function
        function EDFinfoButtonPushed(app, event)
            edfFileAnalyzer(app.dataEDFTimeTable)
        end

        % Button pushed function: StartMatlabEDFfileAnalyzerButton
        function TextAreaValueChanging(app, event)

        end

        % Callback function: Tree
        function TreeCheckedNodesChanged(app, event)
            % checkedNodes = app.Tree.CheckedNodes;
            drawgraphs(app)
        end

        % Button pushed function: DataGDTinladenButton
        function DataGDTinladenButtonPushed(app, event)
        
        cla(app.UIAxes) % clear previous plots
        delete(app.currentlyDrawnNodes) % delete list of drawn plots
            
        uiwait(msgbox("Selecteer GDT bestand van Ergospirometrie metingen","modal"));   % Dialog box for opening GDT file
        [GDT_filename,GDT_path] = uigetfile('*GDT')    % Select GDT file
        
        
        options = detectImportOptions(strcat(GDT_path,GDT_filename), 'FileType','text');
        T = readtable(strcat(GDT_path,GDT_filename), options); 
        T.PaCO2(isnan(T.PaCO2)) = 0; %replace Nan values with 0 so they dont get removed in next step
        gdtfile = T(~any(ismissing(T),2),:);%remove all rows that have missing value or NaN. now we only have the table remaining.
        gdtSamplingTime = seconds(duration(gdtfile.Time, 'InputFormat','mm:ss'));%duration of recording
        gdtSamplePeriod = round(mean(diff(gdtSamplingTime)));%difference between the samples and we take the mean of that
        app.gdtSampleRate = 1/gdtSamplePeriod;
        gdtfile.Time=[];%remove Time collumn
        Time=transpose(seconds(0:gdtSamplePeriod:(height(gdtfile)-1)*gdtSamplePeriod ));%create new time collumn
        app.dataGDTTimeTable=table2timetable(gdtfile,'RowTimes',Time);
        

        drawgraphs(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graph')
            xlabel(app.UIAxes, 'Time (seconds)')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DataEDFinladenButton = uibutton(app.UIFigure, 'push');
            app.DataEDFinladenButton.ButtonPushedFcn = createCallbackFcn(app, @DataEDFinladenButtonPushed, true);
            app.DataEDFinladenButton.Text = 'Data EDF inladen';
            app.StartMatlabEDFfileAnalyzerButton = uibutton(app.UIFigure, 'push');
            app.StartMatlabEDFfileAnalyzerButton.ButtonPushedFcn = createCallbackFcn(app, @TextAreaValueChanging, true);
            app.StartMatlabEDFfileAnalyzerButton.Text = 'Start Matlab EDFfileAnalyzer';
            app.TextArea = uitextarea(app.UIFigure);
            app.DataGDTinladenButton = uibutton(app.UIFigure, 'push');
            app.DataGDTinladenButton.ButtonPushedFcn = createCallbackFcn(app, @DataGDTinladenButtonPushed, true);
            app.DataGDTinladenButton.Text = 'Data GDT inladen';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.EDFNode = uitreenode(app.Tree);
            app.EDFNode.Text = 'EDF';
            app.PCO2Node = uitreenode(app.EDFNode);
            app.PCO2Node.Text = 'PCO2';
            app.PCO2_DCNode = uitreenode(app.EDFNode);
            app.PCO2_DCNode.Text = 'PCO2_DC';
            app.SpO2Node = uitreenode(app.EDFNode);
            app.SpO2Node.Text = 'SpO2';
            app.PRNode = uitreenode(app.EDFNode);
            app.PRNode.Text = 'PR';
            app.GDTNode = uitreenode(app.Tree);
            app.GDTNode.Text = 'GDT';
            app.LoadNode = uitreenode(app.GDTNode);
            app.LoadNode.Text = 'Load';
            app.HRNode_2 = uitreenode(app.GDTNode);
            app.HRNode_2.Text = 'HR';
            app.V_O2_kgNode = uitreenode(app.GDTNode);
            app.V_O2_kgNode.Text = 'V_O2_kg';
            app.V_O2_kgNode_2 = uitreenode(app.GDTNode);
            app.V_O2_kgNode_2.Text = 'V_O2_kg';
            app.V_ENode = uitreenode(app.GDTNode);
            app.V_ENode.Text = 'V_E';
            app.BFNode = uitreenode(app.GDTNode);
            app.BFNode.Text = 'BF';
            app.EqO2Node = uitreenode(app.GDTNode);
            app.EqO2Node.Text = 'EqO2';
            app.EqCO2Node = uitreenode(app.GDTNode);
            app.EqCO2Node.Text = 'EqCO2';
            app.RERNode = uitreenode(app.GDTNode);
            app.RERNode.Text = 'RER';
            app.O2pulseNode = uitreenode(app.GDTNode);
            app.O2pulseNode.Text = 'O2pulse';
            app.PaCO2Node = uitreenode(app.GDTNode);
            app.PaCO2Node.Text = 'PaCO2';
            % Assign Checked Nodes
            app.Tree.CheckedNodes = [app.PCO2Node, app.PCO2_DCNode, app.SpO2Node, app.PRNode, app.GDTNode, app.EDFNode, app.V_ENode, app.V_O2_kgNode_2, app.V_O2_kgNode, app.HRNode_2, app.LoadNode, app.BFNode, app.EqO2Node, app.EqCO2Node, app.RERNode, app.O2pulseNode, app.PaCO2Node];
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @TreeCheckedNodesChanged, true);
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.SynchronizeerButton = uibutton(app.UIFigure, 'state');
            app.SynchronizeerButton.Text = 'Synchronizeer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DataInladen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end